classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        ImageZoomingEnlargingOptionsPanel  matlab.ui.container.Panel
        OpenImageButton                 matlab.ui.control.Button
        ZeroOrderConvolutionPanel       matlab.ui.container.Panel
        RowEditFieldLabel               matlab.ui.control.Label
        RowEditField                    matlab.ui.control.NumericEditField
        ColEditFieldLabel               matlab.ui.control.Label
        ColEditField                    matlab.ui.control.NumericEditField
        WidthEditFieldLabel             matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        ApplyButton                     matlab.ui.control.Button
        RestoreImageButton              matlab.ui.control.Button
        FirstOrderConvolutionPanel      matlab.ui.container.Panel
        RowEditField_2Label             matlab.ui.control.Label
        RowEditField_2                  matlab.ui.control.NumericEditField
        ColEditField_2Label             matlab.ui.control.Label
        ColEditField_2                  matlab.ui.control.NumericEditField
        WidthEditField_2Label           matlab.ui.control.Label
        WidthEditField_2                matlab.ui.control.NumericEditField
        HeightEditField_2Label          matlab.ui.control.Label
        HeightEditField_2               matlab.ui.control.NumericEditField
        ApplyButton_2                   matlab.ui.control.Button
        EnlargeImagePanel               matlab.ui.container.Panel
        EnterZoomScaleEditFieldLabel    matlab.ui.control.Label
        EnterZoomScaleEditField         matlab.ui.control.NumericEditField
        ApplyButton_3                   matlab.ui.control.Button
        TranslateImagePanel             matlab.ui.container.Panel
        ApplyButton_4                   matlab.ui.control.Button
        DirectionButtonGroup            matlab.ui.container.ButtonGroup
        RightButton                     matlab.ui.control.RadioButton
        LeftButton                      matlab.ui.control.RadioButton
        UpButton                        matlab.ui.control.RadioButton
        DownButton                      matlab.ui.control.RadioButton
        WraparoundCheckBox              matlab.ui.control.CheckBox
        TranslatehowmuchEditFieldLabel  matlab.ui.control.Label
        TranslatehowmuchEditField       matlab.ui.control.NumericEditField
        RotateImagePanel                matlab.ui.container.Panel
        AngleofRotationindegreesEditFieldLabel  matlab.ui.control.Label
        AngleofRotationindegreesEditField  matlab.ui.control.NumericEditField
        ApplyButton_5                   matlab.ui.control.Button
        SaveImagetoDiskPanel            matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        ColumnsEditFieldLabel           matlab.ui.control.Label
        ColumnsEditField                matlab.ui.control.NumericEditField
        RowsEditFieldLabel              matlab.ui.control.Label
        RowsEditField                   matlab.ui.control.NumericEditField
        ColorBandsEditFieldLabel        matlab.ui.control.Label
        ColorBandsEditField             matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        image % image that is opened through file browser
        rowZ
        colZ
        widthZ
        heightZ
        row1
        col1
        width1
        height1
        scale
        direction
        wraparound
        transVal
        angle
        currImage
        xpoint
        ypoint
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.WraparoundCheckBox.Value = false;
            ColumnsEditFieldValueChanged(app);
            RowsEditFieldValueChanged(app);
            ColorBandsEditFieldValueChanged(app);
            app.direction = 1;
%             app.yEditField.Editable = 'off';
%             app.xEditField.Editable = 'off';
%             while true
%                 loc = get(app.UIAxes, 'PointerLocation');
%                 app.xpoint = loc(1,1);
%                 app.ypoint = loc(1,2);
%                 yEditFieldValueChanged(app);
%                 xEditFieldValueChanged(app);
%                 pause(0.01);
%             end
            
        end

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            
            
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff';'*.jpeg';'*.png';'*.*'}, 'Select file');
            a= imread(strcat(path,file));
            imshow(a, 'Parent', app.UIAxes);
            app.image = a;
            ColumnsEditFieldValueChanged(app,event);
            RowsEditFieldValueChanged(app,event);
            ColorBandsEditFieldValueChanged(app,event);
        end

        % Value changed function: RowEditField
        function RowEditFieldValueChanged(app, event)
            value = app.RowEditField.Value;
            app.rowZ = value;
        end

        % Value changed function: ColEditField
        function ColEditFieldValueChanged(app, event)
            value = app.ColEditField.Value;
            app.colZ = value;
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            value = app.WidthEditField.Value;
            app.widthZ = value;
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            value = app.HeightEditField.Value;
            app.heightZ = value;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if isempty(app.image)
                f = uifigure;
                uialert(f, 'Please open an image first', 'Error');
            elseif isempty(app.currImage)
                b = zeroOrderConvolution(app.image,app.rowZ,app.colZ,app.heightZ,app.widthZ);
                imshow(b, 'Parent', app.UIAxes);
                app.currImage = b;
            else
                b = zeroOrderConvolution(app.currImage,app.rowZ,app.colZ,app.heightZ,app.widthZ);
                imshow(b, 'Parent', app.UIAxes);
                app.currImage = b;
            end
            ColumnsEditFieldValueChanged(app,event);
            RowsEditFieldValueChanged(app,event);
            ColorBandsEditFieldValueChanged(app,event);
        end

        % Button pushed function: RestoreImageButton
        function RestoreImageButtonPushed(app, event)
            if isempty(app.image)
                f = uifigure;
                uialert(f, 'No previous image found', 'Error');
            else
                imshow(app.image, 'Parent', app.UIAxes);
            end
            app.currImage = [];
            ColumnsEditFieldValueChanged(app,event);
            RowsEditFieldValueChanged(app,event);
            ColorBandsEditFieldValueChanged(app,event);
        end

        % Value changed function: RowEditField_2
        function RowEditField_2ValueChanged(app, event)
            app.row1 = app.RowEditField_2.Value;
        end

        % Value changed function: ColEditField_2
        function ColEditField_2ValueChanged(app, event)
            app.col1 = app.ColEditField_2.Value;
        end

        % Value changed function: WidthEditField_2
        function WidthEditField_2ValueChanged(app, event)
            app.width1 = app.WidthEditField_2.Value;
        end

        % Value changed function: HeightEditField_2
        function HeightEditField_2ValueChanged(app, event)
            app.height1 = app.HeightEditField_2.Value;
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            if isempty(app.image)
                f = uifigure;
                uialert(f,'Please open an image first.', 'Error');
            elseif isempty(app.currImage)
                b = firstOrderConvolution(app.image,app.row1,app.col1,app.height1,app.width1);
                imshow(b, 'Parent', app.UIAxes);
                app.currImage = b;
            else
                b = firstOrderConvolution(app.currImage,app.row1,app.col1,app.height1,app.width1);
                imshow(b, 'Parent', app.UIAxes);
                app.currImage = b;
            end
            ColumnsEditFieldValueChanged(app,event);
            RowsEditFieldValueChanged(app,event);
            ColorBandsEditFieldValueChanged(app,event);
        end

        % Value changed function: EnterZoomScaleEditField
        function EnterZoomScaleEditFieldValueChanged(app, event)
            app.scale = app.EnterZoomScaleEditField.Value;
            
        end

        % Button pushed function: ApplyButton_3
        function ApplyButton_3Pushed(app, event)
            if isempty(app.image)
                f = uifigure;
                uialert(f,'Please open an image first.', 'Error');
            elseif isempty(app.currImage)
                b = linearInterpolationEnlarge(app.image, app.scale);
                imshow(b, 'Parent', app.UIAxes);
                app.currImage = b;
            else
                b = linearInterpolationEnlarge(app.image, app.scale);
                imshow(b, 'Parent', app.UIAxes);
                app.currImage = b;
            end
            ColumnsEditFieldValueChanged(app,event);
            RowsEditFieldValueChanged(app,event);
            ColorBandsEditFieldValueChanged(app,event);
        end

        % Selection changed function: DirectionButtonGroup
        function DirectionButtonGroupSelectionChanged(app, event)
            if app.RightButton.Value==true
                app.direction = 1;
            elseif app.LeftButton.Value==true
                app.direction = 2;
            elseif app.UpButton.Value==true
                app.direction = 3;
            else
                app.direction = 4;
            end
        end

        % Value changed function: WraparoundCheckBox
        function WraparoundCheckBoxValueChanged(app, event)
            app.wraparound = app.WraparoundCheckBox.Value;
        end

        % Value changed function: TranslatehowmuchEditField
        function TranslatehowmuchEditFieldValueChanged(app, event)
            app.transVal = app.TranslatehowmuchEditField.Value;
        end

        % Button pushed function: ApplyButton_4
        function ApplyButton_4Pushed(app, event)
            if isempty(app.image)
                f = uifigure;
                uialert(f, 'Please open an image first', 'Error');
                b = [];
            elseif isempty(app.currImage)
                if app.direction==1
                    b = translateRight(app.image,app.transVal,app.wraparound);
                elseif app.direction==2
                    b = translateLeft(app.image,app.transVal,app.wraparound);
                elseif app.direction==4
                    b = translateDown(app.image,app.transVal,app.wraparound);
                else
                    b = translateUp(app.image,app.transVal,app.wraparound);
                end
            else
                if app.direction==1
                    b = translateRight(app.currImage,app.transVal,app.wraparound);
                elseif app.direction==2
                    b = translateLeft(app.currImage,app.transVal,app.wraparound);
                elseif app.direction==4
                    b = translateDown(app.currImage,app.transVal,app.wraparound);
                else
                    b = translateUp(app.currImage,app.transVal,app.wraparound);
                end
            end
            if isempty(b)==false
                imshow(b, 'Parent', app.UIAxes);
                app.currImage = b;
            end
            ColumnsEditFieldValueChanged(app,event);
            RowsEditFieldValueChanged(app,event);
            ColorBandsEditFieldValueChanged(app,event);
        end

        % Value changed function: AngleofRotationindegreesEditField
        function AngleofRotationindegreesEditFieldValueChanged(app, event)
            app.angle = app.AngleofRotationindegreesEditField.Value;
        end

        % Button pushed function: ApplyButton_5
        function ApplyButton_5Pushed(app, event)
            if isempty(app.image)
                f = uifigure;
                uialert(f, 'Please open an image first.', 'Error');
                b = [];
            elseif isempty(app.currImage)
                b = imageRotate(app.image,app.angle);
            else
                b = imageRotate(app.currImage,app.angle);
            end
            if isempty(b)==false
                imshow(b,'Parent',app.UIAxes);
                app.currImage = b;
            end
            ColumnsEditFieldValueChanged(app,event);
            RowsEditFieldValueChanged(app,event);
            ColorBandsEditFieldValueChanged(app,event);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.image)
                f = uifigure;
                uialert(f,'No image is opened and editted.','Error');
            elseif isempty(app.currImage)
                [file,path] = uiputfile('*.*');
                filename = fullfile(path,file);
                imwrite(app.image,filename);
            else
                [file,path] = uiputfile('*.*');
                filename = fullfile(path,file);
                imwrite(app.currImage,filename);
            end
        end

        % Value changed function: ColumnsEditField
        function ColumnsEditFieldValueChanged(app, event)
            app.ColumnsEditField.Editable = 'off';
            if isempty(app.currImage) && isempty(app.image)==false
                [p,q,r] = size(app.image);
                app.ColumnsEditField.Value = q;
            elseif isempty(app.image)==false
                [p,q,r] = size(app.currImage);
                app.ColumnsEditField.Value = q;
            else
                app.ColumnsEditField.Value = 0;
            end
        end

        % Value changed function: RowsEditField
        function RowsEditFieldValueChanged(app, event)
            app.RowsEditField.Editable = 'off';
            if isempty(app.currImage) && isempty(app.image)==false
                [p,q,r] = size(app.image);
                app.RowsEditField.Value = p;
            elseif isempty(app.image)==false
                [p,q,r] = size(app.currImage);
                app.RowsEditField.Value = p;
            else
                app.RowsEditField.Value = 0;
            end
        end

        % Value changed function: ColorBandsEditField
        function ColorBandsEditFieldValueChanged(app, event)
            app.ColorBandsEditField.Editable = 'off';
            if isempty(app.currImage) && isempty(app.image)==false
                [p,q,r] = size(app.image);
                app.ColorBandsEditField.Value = r;
            elseif isempty(app.image)==false
                [p,q,r] = size(app.currImage);
                app.ColorBandsEditField.Value = r;
            else
                app.ColorBandsEditField.Value = 0;
            end
        end

        % Callback function
        function yEditFieldValueChanged(app, event)
            app.yEditField.Value = app.ypoint;
        end

        % Callback function
        function xEditFieldValueChanged(app, event)
            app.xEditField.Value = app.xpoint;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'IMAGE')
            app.ImageZoomingEnlargingOptionsPanel = uipanel(app.UIFigure);
            app.ImageZoomingEnlargingOptionsPanel.Title = 'Image Zooming/Enlarging Options';
            app.OpenImageButton = uibutton(app.ImageZoomingEnlargingOptionsPanel, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.ZeroOrderConvolutionPanel = uipanel(app.ImageZoomingEnlargingOptionsPanel);
            app.ZeroOrderConvolutionPanel.Title = 'Zero Order Convolution';
            app.RowEditFieldLabel = uilabel(app.ZeroOrderConvolutionPanel);
            app.RowEditFieldLabel.Text = 'Row';
            app.RowEditField = uieditfield(app.ZeroOrderConvolutionPanel, 'numeric');
            app.RowEditField.ValueChangedFcn = createCallbackFcn(app, @RowEditFieldValueChanged, true);
            app.ColEditFieldLabel = uilabel(app.ZeroOrderConvolutionPanel);
            app.ColEditFieldLabel.Text = 'Col';
            app.ColEditField = uieditfield(app.ZeroOrderConvolutionPanel, 'numeric');
            app.ColEditField.ValueChangedFcn = createCallbackFcn(app, @ColEditFieldValueChanged, true);
            app.WidthEditFieldLabel = uilabel(app.ZeroOrderConvolutionPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.ZeroOrderConvolutionPanel, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.HeightEditFieldLabel = uilabel(app.ZeroOrderConvolutionPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.ZeroOrderConvolutionPanel, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.ApplyButton = uibutton(app.ZeroOrderConvolutionPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.RestoreImageButton = uibutton(app.ImageZoomingEnlargingOptionsPanel, 'push');
            app.RestoreImageButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreImageButtonPushed, true);
            app.RestoreImageButton.Text = 'Restore Image';
            app.FirstOrderConvolutionPanel = uipanel(app.ImageZoomingEnlargingOptionsPanel);
            app.FirstOrderConvolutionPanel.Title = 'First Order Convolution';
            app.RowEditField_2Label = uilabel(app.FirstOrderConvolutionPanel);
            app.RowEditField_2Label.Text = 'Row';
            app.RowEditField_2 = uieditfield(app.FirstOrderConvolutionPanel, 'numeric');
            app.RowEditField_2.ValueChangedFcn = createCallbackFcn(app, @RowEditField_2ValueChanged, true);
            app.ColEditField_2Label = uilabel(app.FirstOrderConvolutionPanel);
            app.ColEditField_2Label.Text = 'Col';
            app.ColEditField_2 = uieditfield(app.FirstOrderConvolutionPanel, 'numeric');
            app.ColEditField_2.ValueChangedFcn = createCallbackFcn(app, @ColEditField_2ValueChanged, true);
            app.WidthEditField_2Label = uilabel(app.FirstOrderConvolutionPanel);
            app.WidthEditField_2Label.Text = 'Width';
            app.WidthEditField_2 = uieditfield(app.FirstOrderConvolutionPanel, 'numeric');
            app.WidthEditField_2.ValueChangedFcn = createCallbackFcn(app, @WidthEditField_2ValueChanged, true);
            app.HeightEditField_2Label = uilabel(app.FirstOrderConvolutionPanel);
            app.HeightEditField_2Label.Text = 'Height';
            app.HeightEditField_2 = uieditfield(app.FirstOrderConvolutionPanel, 'numeric');
            app.HeightEditField_2.ValueChangedFcn = createCallbackFcn(app, @HeightEditField_2ValueChanged, true);
            app.ApplyButton_2 = uibutton(app.FirstOrderConvolutionPanel, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.EnlargeImagePanel = uipanel(app.ImageZoomingEnlargingOptionsPanel);
            app.EnlargeImagePanel.Title = 'Enlarge Image';
            app.EnterZoomScaleEditFieldLabel = uilabel(app.EnlargeImagePanel);
            app.EnterZoomScaleEditFieldLabel.Text = 'Enter Zoom Scale';
            app.EnterZoomScaleEditField = uieditfield(app.EnlargeImagePanel, 'numeric');
            app.EnterZoomScaleEditField.ValueChangedFcn = createCallbackFcn(app, @EnterZoomScaleEditFieldValueChanged, true);
            app.ApplyButton_3 = uibutton(app.EnlargeImagePanel, 'push');
            app.ApplyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_3Pushed, true);
            app.ApplyButton_3.Text = 'Apply';
            app.TranslateImagePanel = uipanel(app.UIFigure);
            app.TranslateImagePanel.Title = 'Translate Image';
            app.ApplyButton_4 = uibutton(app.TranslateImagePanel, 'push');
            app.ApplyButton_4.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_4Pushed, true);
            app.ApplyButton_4.Text = 'Apply';
            app.DirectionButtonGroup = uibuttongroup(app.TranslateImagePanel);
            app.DirectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DirectionButtonGroupSelectionChanged, true);
            app.DirectionButtonGroup.Title = 'Direction';
            app.RightButton = uiradiobutton(app.DirectionButtonGroup);
            app.RightButton.Text = 'Right';
            app.RightButton.Value = true;
            app.LeftButton = uiradiobutton(app.DirectionButtonGroup);
            app.LeftButton.Text = 'Left';
            app.UpButton = uiradiobutton(app.DirectionButtonGroup);
            app.UpButton.Text = 'Up';
            app.DownButton = uiradiobutton(app.DirectionButtonGroup);
            app.DownButton.Text = 'Down';
            app.WraparoundCheckBox = uicheckbox(app.TranslateImagePanel);
            app.WraparoundCheckBox.ValueChangedFcn = createCallbackFcn(app, @WraparoundCheckBoxValueChanged, true);
            app.WraparoundCheckBox.Text = 'Wraparound';
            app.TranslatehowmuchEditFieldLabel = uilabel(app.TranslateImagePanel);
            app.TranslatehowmuchEditFieldLabel.Text = 'Translate how much?';
            app.TranslatehowmuchEditField = uieditfield(app.TranslateImagePanel, 'numeric');
            app.TranslatehowmuchEditField.ValueChangedFcn = createCallbackFcn(app, @TranslatehowmuchEditFieldValueChanged, true);
            app.RotateImagePanel = uipanel(app.UIFigure);
            app.RotateImagePanel.Title = 'Rotate Image';
            app.AngleofRotationindegreesEditFieldLabel = uilabel(app.RotateImagePanel);
            app.AngleofRotationindegreesEditFieldLabel.Text = 'Angle of Rotation in degrees';
            app.AngleofRotationindegreesEditField = uieditfield(app.RotateImagePanel, 'numeric');
            app.AngleofRotationindegreesEditField.ValueChangedFcn = createCallbackFcn(app, @AngleofRotationindegreesEditFieldValueChanged, true);
            app.ApplyButton_5 = uibutton(app.RotateImagePanel, 'push');
            app.ApplyButton_5.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_5Pushed, true);
            app.ApplyButton_5.Text = 'Apply';
            app.SaveImagetoDiskPanel = uipanel(app.UIFigure);
            app.SaveImagetoDiskPanel.Title = 'Save Image to Disk';
            app.SaveButton = uibutton(app.SaveImagetoDiskPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ColumnsEditFieldLabel = uilabel(app.UIFigure);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnsEditFieldValueChanged, true);
            app.RowsEditFieldLabel = uilabel(app.UIFigure);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @RowsEditFieldValueChanged, true);
            app.ColorBandsEditFieldLabel = uilabel(app.UIFigure);
            app.ColorBandsEditFieldLabel.Text = 'Color Bands';
            app.ColorBandsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColorBandsEditField.ValueChangedFcn = createCallbackFcn(app, @ColorBandsEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end